classdef wizard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ExportPanel                matlab.ui.container.Panel
        roa_roi_toggle             matlab.ui.control.CheckBox
        roa_comp_toggle            matlab.ui.control.CheckBox
        destination_set_button     matlab.ui.control.Button
        DestinationEditFieldLabel  matlab.ui.control.Label
        destination                matlab.ui.control.EditField
        multitab_toggle            matlab.ui.control.CheckBox
        selection_summary          matlab.ui.control.Label
        TracesprfileLabel          matlab.ui.control.Label
        traces_pr_file             matlab.ui.control.NumericEditField
        Label                      matlab.ui.control.Label
        resample_toggle            matlab.ui.control.CheckBox
        framessecLabel             matlab.ui.control.Label
        fps_input                  matlab.ui.control.NumericEditField
        export_button              matlab.ui.control.Button
    end

    
    properties (Access = private)
        dlocs % Description
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function on_startup(app, tseries)
            app.UIFigure.Name = "Export wizard";
            app.dlocs = tseries;
            app.destination.Value = pwd();
            
            if nargin > 1
                app.selection_summary.Text = length(tseries) + " DataLocations selected";
            else
                app.selection_summary.Text = "No DataLocations selected - cannot export";
                app.export_button.Enable = false;
            end
        end

        % Button pushed function: destination_set_button
        function on_dest_setclick(app, event)
            dirpath = uigetdir();
            app.destination.Value = dirpath;
            figure(app.UIFigure);
        end

        % Button pushed function: export_button
        function do_export(app, event)
            import begonia.data_management.multitable.*;

            mtab = MultiTable();
            entities = string({app.dlocs.name});
            
            cats = strings(0);
       
            % calsium data:
            w = waitbar(0.1, "Building table");
            if app.roa_comp_toggle.Value
                add_ca_compartment(mtab, entities, "roa comp: active fraction", app.dlocs, "active_fraction");
                add_ca_compartment(mtab, entities, "roa comp: new events", app.dlocs, "new_events");
                add_ca_compartment(mtab, entities, "roa comp: active event count", app.dlocs, "active_event_count");
                add_ca_compartment(mtab, entities, "roa comp: new event duration", app.dlocs, "new_event_duration");
                add_ca_compartment(mtab, entities, "roa comp: events finished", app.dlocs, "finished_events");
                
                cats = [cats "roa comp: active fraction", "roa comp: new events","roa comp: active event count", "roa comp: new event duration", "roa comp: events finished"];
            end
            
            if app.roa_roi_toggle.Value
                add_ca_roi(mtab, entities, "roa rois: active fraction", app.dlocs, "roi_signal_rpa");
                cats = [cats "roa rois: active fraction"];
            end
            
            % export data:
            waitbar(0.2, w,"Getting traces (can take a while)");
            traces = mtab.by_cat(cats);
            if app.resample_toggle.Value
                waitbar(0.6, w,"Resampling (can take a while too)");
                resample(traces, 1/app.fps_input.Value);
            end
            
            waitbar(0.9, w,"Writing results (yup, it takes a while)");
            expdir = export_csv(traces, app.destination.Value, app.traces_pr_file.Value);
            
            % export multitable if asked:
            if app.multitab_toggle.Value
                mtabfile = fullfile(expdir, "Multitable.mat");
                save(mtabfile, "mtab");
            end
            
            delete(w);
            figure(app.UIFigure);
        end

        % Value changed function: resample_toggle
        function on_resample_toggle(app, event)
            value = app.resample_toggle.Value;
            app.fps_input.Enable = app.resample_toggle.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ExportPanel = uipanel(app.UIFigure);
            app.ExportPanel.Title = 'Export';
            app.roa_roi_toggle = uicheckbox(app.ExportPanel);
            app.roa_roi_toggle.Text = 'RoI activity (RoA)';
            app.roa_comp_toggle = uicheckbox(app.ExportPanel);
            app.roa_comp_toggle.Text = 'Compartment activity (RoA)';
            app.destination_set_button = uibutton(app.ExportPanel, 'push');
            app.destination_set_button.ButtonPushedFcn = createCallbackFcn(app, @on_dest_setclick, true);
            app.destination_set_button.Text = 'Set ';
            app.DestinationEditFieldLabel = uilabel(app.ExportPanel);
            app.DestinationEditFieldLabel.Text = 'Destination:';
            app.destination = uieditfield(app.ExportPanel, 'text');
            app.multitab_toggle = uicheckbox(app.ExportPanel);
            app.multitab_toggle.Text = 'Multitable (.mat)';
            app.selection_summary = uilabel(app.ExportPanel);
            app.selection_summary.Text = 'Selection summary';
            app.TracesprfileLabel = uilabel(app.ExportPanel);
            app.TracesprfileLabel.Text = 'Traces pr. file:';
            app.traces_pr_file = uieditfield(app.ExportPanel, 'numeric');
            app.traces_pr_file.Value = 500;
            app.Label = uilabel(app.ExportPanel);
            app.Label.Text = {'NOTE: not all outputs can be exported. To access full set of outputs, use '; 'datalocations directly in Matlab + see documentation.'};
            app.resample_toggle = uicheckbox(app.ExportPanel);
            app.resample_toggle.ValueChangedFcn = createCallbackFcn(app, @on_resample_toggle, true);
            app.resample_toggle.Text = 'Resample';
            app.framessecLabel = uilabel(app.ExportPanel);
            app.framessecLabel.Enable = 'off';
            app.framessecLabel.Text = 'frames/sec';
            app.fps_input = uieditfield(app.ExportPanel, 'numeric');
            app.fps_input.Enable = 'off';
            app.fps_input.Value = 30;
            app.export_button = uibutton(app.UIFigure, 'push');
            app.export_button.ButtonPushedFcn = createCallbackFcn(app, @do_export, true);
            app.export_button.Text = 'Export';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = wizard(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)on_startup(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end